classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SpecificationParamsPanel       matlab.ui.container.Panel
        BeforeInfoLabel_2              matlab.ui.control.Label
        UploadReferenceButton          matlab.ui.control.Button
        BrighteningParamsPanel         matlab.ui.container.Panel
        GainEditField                  matlab.ui.control.NumericEditField
        GainaEditFieldLabel            matlab.ui.control.Label
        OffsetEditField                matlab.ui.control.NumericEditField
        OffsetbEditFieldLabel          matlab.ui.control.Label
        GaussianFilterParamsPanel      matlab.ui.container.Panel
        SigmaEditField                 matlab.ui.control.NumericEditField
        BlurSpreadEditFieldLabel       matlab.ui.control.Label
        Image4_3                       matlab.ui.control.Image
        Image4_4                       matlab.ui.control.Image
        Image4_2                       matlab.ui.control.Image
        Image3_2                       matlab.ui.control.Image
        Image3                         matlab.ui.control.Image
        Image4                         matlab.ui.control.Image
        Image2                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        ChooseImagePanel               matlab.ui.container.Panel
        UploadImageButton              matlab.ui.control.Button
        ResultPanel                    matlab.ui.container.Panel
        HistogramLabel_3               matlab.ui.control.Label
        ResultImage                    matlab.ui.control.Image
        ResultImageLabel               matlab.ui.control.Label
        ResultStats                    matlab.ui.control.ListBox
        ResultHistogram                matlab.ui.control.UIAxes
        ReferencePanel                 matlab.ui.container.Panel
        HistogramLabel_2               matlab.ui.control.Label
        ReferenceImage                 matlab.ui.control.Image
        ReferenceImageLabel            matlab.ui.control.Label
        ReferenceStats                 matlab.ui.control.ListBox
        ReferenceHistogram             matlab.ui.control.UIAxes
        SaveButton                     matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        UndoButton                     matlab.ui.control.Button
        ApplyButton                    matlab.ui.control.Button
        MethodsButtonGroup             matlab.ui.container.ButtonGroup
        WienerButton                   matlab.ui.control.ToggleButton
        InverseButton                  matlab.ui.control.ToggleButton
        NotchButton                    matlab.ui.control.ToggleButton
        GaussianButton                 matlab.ui.control.ToggleButton
        ButterworthButton              matlab.ui.control.ToggleButton
        PSFButton                      matlab.ui.control.ToggleButton
        Label                          matlab.ui.control.Label
        IdealButton                    matlab.ui.control.ToggleButton
        PeriodicNoiseRestorationLabel  matlab.ui.control.Label
        BHPFButton                     matlab.ui.control.ToggleButton
        GHPFButton                     matlab.ui.control.ToggleButton
        DCButton                       matlab.ui.control.ToggleButton
        BLPFButton                     matlab.ui.control.ToggleButton
        GLPFButton                     matlab.ui.control.ToggleButton
        HomomorphicButton              matlab.ui.control.ToggleButton
        BrightnessLabel                matlab.ui.control.Label
        HighPassFilterLabel            matlab.ui.control.Label
        LowPassFilterLabel             matlab.ui.control.Label
        IHPFButton                     matlab.ui.control.ToggleButton
        ILPFButton                     matlab.ui.control.ToggleButton
        BeforePanel                    matlab.ui.container.Panel
        HistogramLabel                 matlab.ui.control.Label
        BeforeImage                    matlab.ui.control.Image
        InputImageLabel                matlab.ui.control.Label
        BeforeStats                    matlab.ui.control.ListBox
        BeforeHistogram                matlab.ui.control.UIAxes
        ContrastStretchParamsPanel     matlab.ui.container.Panel
        LowInputEditField              matlab.ui.control.NumericEditField
        r1EditFieldLabel               matlab.ui.control.Label
        HighInputEditField             matlab.ui.control.NumericEditField
        r2EditFieldLabel               matlab.ui.control.Label
        PowerLawParamsPanel            matlab.ui.container.Panel
        GammaEditField                 matlab.ui.control.NumericEditField
        GammaLabel                     matlab.ui.control.Label
        EqualizationParamsPanel        matlab.ui.container.Panel
        BeforeInfoLabel_3              matlab.ui.control.Label
        NegativeParamsPanel            matlab.ui.container.Panel
        BeforeInfoLabel_4              matlab.ui.control.Label
        LogTransformParamsPanel        matlab.ui.container.Panel
        BeforeInfoLabel_5              matlab.ui.control.Label
        SharpenFilterParamsPanel       matlab.ui.container.Panel
        SharpenModeDropDown            matlab.ui.control.DropDown
        ModeDropDownLabel              matlab.ui.control.Label
        SharpenAmountEditField         matlab.ui.control.NumericEditField
        StrengthEditFieldLabel         matlab.ui.control.Label
        FilterParamsPanel              matlab.ui.container.Panel
        KernelSizeDropDown             matlab.ui.control.DropDown
        KernelDropDown_2Label          matlab.ui.control.Label
    end

    
    properties (Access = private)
        OriginalImage = []
        CurrentImage = []       
        RefImageData = []
        History = {}            
        Steps = strings(0)     
        ParamPanels = struct()
        ImageName = ""
    end
    
    methods (Access = private)
        
        function showParamsPanel(app)
            method = app.MethodsButtonGroup.SelectedObject.Tag;
            panels = struct2cell(app.ParamPanels);
            set([panels{:}], 'Visible', 'off');
            app.ParamPanels.(method).Visible = 'on';

            app.ReferencePanel.Visible = strcmp(method, 'specification');
        end

        function params = readParams(app)
            params = struct( ...
                'gain',        app.GainEditField.Value, ...
                'offset',      app.OffsetEditField.Value, ...
                'gamma',       app.GammaEditField.Value, ...
                'r1',          app.LowInputEditField.Value, ...
                'r2',          app.HighInputEditField.Value, ...
                'ksize',       app.KernelSizeDropDown.Value, ...
                'sigma',       app.SigmaEditField.Value, ...
                'sharpenMode', app.SharpenModeDropDown.Value, ...
                'amount',      app.SharpenAmountEditField.Value);
        end

        function [img, fileName] = pickImage(app, dialogTitle)
            img = [];
            [fileName, folder] = uigetfile({'*.png;*.jpg;*.jpeg;*.bmp;*.tif', 'Image Files'}, dialogTitle);
            figure(app.UIFigure);  
            if isequal(fileName, 0)
                return;
            end
            img = loadImage(fullfile(folder, fileName));
        end

        function showImageInfo(app, img, imageComp, ax, statsList)
            arguments
                app
                img {mustBeNumeric}
                imageComp matlab.ui.control.Image
                ax matlab.ui.control.UIAxes
                statsList matlab.ui.control.ListBox
            end

            nChannels = size(img, 3);
            if nChannels == 1
                imageComp.ImageSource = repmat(img, 1, 1, 3);   % uiimage needs RGB
                colors = {'k'};
                prefixes = "";
                histTitle = "Grayscale";
            else
                imageComp.ImageSource = img;
                colors = {'r', 'g', 'b'};
                prefixes = ["R ", "G ", "B "];
                histTitle = "RGB Histogram";
            end

            statLines = cell(1, nChannels);
            cla(ax);
            hold(ax, 'on');
            for k = 1:nChannels
                channel = img(:, :, k);
                plotHistogramToAxes(ax, computeHistogram(channel), colors{k}, histTitle);
                statLines{k} = formatStats(app, computeImageStats(channel), prefixes(k));
            end
            hold(ax, 'off');

            statsList.Items = [{'Statistics'}, statLines{:}];
        end

        function items = formatStats(~, stats, prefix)
            items = { ...
                sprintf('%sMin / Max: %d / %d', prefix, stats.minVal, stats.maxVal), ...
                sprintf('%sMean: %.2f', prefix, stats.meanVal), ...
                sprintf('%sStd: %.2f', prefix, stats.stdVal), ...
                sprintf('%sEntropy: %.3f', prefix, stats.entropy)};
        end

        function refreshResult(app)
            showImageInfo(app, app.CurrentImage, app.ResultImage, app.ResultHistogram, app.ResultStats);
            if isempty(app.Steps)
                app.ResultPanel.Title = 'Result';
            else
                app.ResultPanel.Title = "Result — " + strjoin(app.Steps, "  →  ");
            end
            updateButtonStates(app);
        end

        function updateButtonStates(app)
            hasImage = ~isempty(app.OriginalImage);
            hasSteps = ~isempty(app.Steps);
            app.ApplyButton.Enable = hasImage;
            app.UndoButton.Enable = hasSteps;
            app.ResetButton.Enable = hasSteps;
            app.SaveButton.Enable = hasSteps;
        end

        function resetToOriginal(app)
            app.CurrentImage = app.OriginalImage;
            app.History = {};
            app.Steps = strings(0);
            refreshResult(app);
            
        end
        
        
        function highlightSelectedMethod(app)
            ACTIVE_BG = [1 0.1412 0.5216];    
            ACTIVE_FG = [1 1 1];               
            IDLE_BG   = [0.9765 0.8706 0.898];  
            IDLE_FG   = [0 0.1765 0.1216];      

            buttons = findobj(app.MethodsButtonGroup, 'Type', 'uitogglebutton');
            set(buttons, 'BackgroundColor', IDLE_BG, 'FontColor', IDLE_FG);
            set(app.MethodsButtonGroup.SelectedObject, 'BackgroundColor', ACTIVE_BG, 'FontColor', ACTIVE_FG);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath(fullfile(fileparts(mfilename('fullpath')), 'src')));
            highlightSelectedMethod(app);

            app.ParamPanels = struct( ...
                'brightening',   app.BrighteningParamsPanel, ...
                'negative',      app.NegativeParamsPanel, ...
                'log',           app.LogTransformParamsPanel, ...
                'power',         app.PowerLawParamsPanel, ...
                'contrast',      app.ContrastStretchParamsPanel, ...
                'equalization',  app.EqualizationParamsPanel, ...
                'specification', app.SpecificationParamsPanel, ...
                'mean',          app.FilterParamsPanel, ...
                'median',        app.FilterParamsPanel, ...
                'gaussian',      app.GaussianFilterParamsPanel, ...
                'sharpen',       app.SharpenFilterParamsPanel);

            app.KernelSizeDropDown.ItemsData = [3 5 7 9];
            app.SharpenModeDropDown.ItemsData = {'4-neighbor', '8-neighbor'};

            showParamsPanel(app);
            updateButtonStates(app);
        end

        % Selection changed function: MethodsButtonGroup
        function MethodsButtonGroupSelectionChanged(app, event)
            showParamsPanel(app);         
            highlightSelectedMethod(app);
        end

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            [img, fileName] = pickImage(app, 'Choose Image');
            if isempty(img)
                return;
            end

            app.OriginalImage = img;
            [~, app.ImageName] = fileparts(fileName);
            showImageInfo(app, img, app.BeforeImage, app.BeforeHistogram, app.BeforeStats);

            gray = toGrayscale(img);
            app.LowInputEditField.Value = computePercentile(gray, 0.01);
            app.HighInputEditField.Value = computePercentile(gray, 0.99);

            resetToOriginal(app);
        end

        % Button pushed function: UploadReferenceButton
        function UploadReferenceButtonPushed(app, event)
            img = pickImage(app, 'Choose Reference Image');
            if isempty(img)
                return;
            end

            app.RefImageData = img;
            showImageInfo(app, img, app.ReferenceImage, app.ReferenceHistogram, app.ReferenceStats);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            method = app.MethodsButtonGroup.SelectedObject.Tag;
            try
                [outImg, label] = enhanceImage(app.CurrentImage, method, readParams(app), app.RefImageData);
            catch err
                uialert(app.UIFigure, err.message, 'Cannot Apply Method');
                return;
            end

            % methods stack: each apply works on the previous result
            app.History{end+1} = app.CurrentImage;
            app.Steps(end+1) = label;
            app.CurrentImage = outImg;
            refreshResult(app);
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            app.CurrentImage = app.History{end};
            app.History(end) = [];
            app.Steps(end) = [];
            refreshResult(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            resetToOriginal(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            outDir = fullfile(fileparts(mfilename('fullpath')), 'tests', 'output');
            if ~isfolder(outDir)
                mkdir(outDir);
            end
            defaultName = char(fullfile(outDir, app.ImageName + "_enhanced.png"));
            [fileName, folder] = uiputfile( ...
                {'*.png', 'PNG image'; '*.jpg', 'JPEG image'; '*.bmp', 'Bitmap image'}, ...
                'Save Enhanced Image', defaultName);
            figure(app.UIFigure);   
            if isequal(fileName, 0)
                return;
            end

            [~, baseName, ext] = fileparts(fileName);
            if isempty(ext)
                fileName = [fileName '.png'];
            end

            histPath = fullfile(folder, [baseName '_histogram.png']);
            histTitle = "Histogram";

            try
                imwrite(app.CurrentImage, fullfile(folder, fileName));
                saveHistogram(app.CurrentImage, histPath, histTitle);
            catch err
                uialert(app.UIFigure, err.message, 'Cannot Save Image');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'ParaPix';
            app.FilterParamsPanel = uipanel(app.UIFigure);
            app.FilterParamsPanel.Title = 'Filter Params';
            app.KernelDropDown_2Label = uilabel(app.FilterParamsPanel);
            app.KernelDropDown_2Label.Text = 'Kernel';
            app.KernelSizeDropDown = uidropdown(app.FilterParamsPanel);
            app.KernelSizeDropDown.Items = {'3 x 3', '5 x 5', '7 x 7', '9 x 9'};
            app.KernelSizeDropDown.Value = '3 x 3';
            app.SharpenFilterParamsPanel = uipanel(app.UIFigure);
            app.SharpenFilterParamsPanel.Title = 'Sharpen Filter Params';
            app.StrengthEditFieldLabel = uilabel(app.SharpenFilterParamsPanel);
            app.StrengthEditFieldLabel.Text = 'Strength';
            app.SharpenAmountEditField = uieditfield(app.SharpenFilterParamsPanel, 'numeric');
            app.SharpenAmountEditField.Limits = [0.1 2];
            app.SharpenAmountEditField.Value = 1;
            app.ModeDropDownLabel = uilabel(app.SharpenFilterParamsPanel);
            app.ModeDropDownLabel.Text = 'Mode';
            app.SharpenModeDropDown = uidropdown(app.SharpenFilterParamsPanel);
            app.SharpenModeDropDown.Items = {'Standard (4-neighbors)', 'Strong (8-neighbors)'};
            app.SharpenModeDropDown.Value = 'Standard (4-neighbors)';
            app.LogTransformParamsPanel = uipanel(app.UIFigure);
            app.LogTransformParamsPanel.Title = 'Log Transform Params';
            app.BeforeInfoLabel_5 = uilabel(app.LogTransformParamsPanel);
            app.BeforeInfoLabel_5.Text = 'No parameters.';
            app.NegativeParamsPanel = uipanel(app.UIFigure);
            app.NegativeParamsPanel.Title = 'Negative Params';
            app.BeforeInfoLabel_4 = uilabel(app.NegativeParamsPanel);
            app.BeforeInfoLabel_4.Text = 'No parameters.';
            app.EqualizationParamsPanel = uipanel(app.UIFigure);
            app.EqualizationParamsPanel.Title = 'Equalization Params';
            app.BeforeInfoLabel_3 = uilabel(app.EqualizationParamsPanel);
            app.BeforeInfoLabel_3.Text = 'No parameters.';
            app.PowerLawParamsPanel = uipanel(app.UIFigure);
            app.PowerLawParamsPanel.Title = 'Power Law Params';
            app.GammaLabel = uilabel(app.PowerLawParamsPanel);
            app.GammaLabel.Text = 'Gamma (γ)';
            app.GammaEditField = uieditfield(app.PowerLawParamsPanel, 'numeric');
            app.GammaEditField.Limits = [0.1 5];
            app.GammaEditField.Value = 1;
            app.ContrastStretchParamsPanel = uipanel(app.UIFigure);
            app.ContrastStretchParamsPanel.Title = 'Contrast Stretch Params';
            app.r2EditFieldLabel = uilabel(app.ContrastStretchParamsPanel);
            app.r2EditFieldLabel.Text = 'r2';
            app.HighInputEditField = uieditfield(app.ContrastStretchParamsPanel, 'numeric');
            app.HighInputEditField.Limits = [0 255];
            app.HighInputEditField.Value = 255;
            app.r1EditFieldLabel = uilabel(app.ContrastStretchParamsPanel);
            app.r1EditFieldLabel.Text = 'r1';
            app.LowInputEditField = uieditfield(app.ContrastStretchParamsPanel, 'numeric');
            app.LowInputEditField.Limits = [0 255];
            app.BeforePanel = uipanel(app.UIFigure);
            app.BeforePanel.Title = 'Before';
            app.BeforeHistogram = uiaxes(app.BeforePanel);
            title(app.BeforeHistogram, 'Title')
            xlabel(app.BeforeHistogram, 'X')
            ylabel(app.BeforeHistogram, 'Y')
            zlabel(app.BeforeHistogram, 'Z')
            app.BeforeStats = uilistbox(app.BeforePanel);
            app.BeforeStats.Items = {'Statistics', 'No statistics yet.'};
            app.BeforeStats.Value = 'Statistics';
            app.InputImageLabel = uilabel(app.BeforePanel);
            app.InputImageLabel.Text = 'Input Image';
            app.BeforeImage = uiimage(app.BeforePanel);
            app.HistogramLabel = uilabel(app.BeforePanel);
            app.HistogramLabel.Text = 'Histogram';
            app.MethodsButtonGroup = uibuttongroup(app.UIFigure);
            app.MethodsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MethodsButtonGroupSelectionChanged, true);
            app.MethodsButtonGroup.Title = 'Methods';
            app.ILPFButton = uitogglebutton(app.MethodsButtonGroup);
            app.ILPFButton.Tag = 'brightening';
            app.ILPFButton.Text = 'ILPF';
            app.ILPFButton.Value = true;
            app.IHPFButton = uitogglebutton(app.MethodsButtonGroup);
            app.IHPFButton.Tag = 'equalization';
            app.IHPFButton.Text = 'IHPF';
            app.LowPassFilterLabel = uilabel(app.MethodsButtonGroup);
            app.LowPassFilterLabel.Text = 'Low Pass Filter';
            app.HighPassFilterLabel = uilabel(app.MethodsButtonGroup);
            app.HighPassFilterLabel.Text = 'High Pass Filter';
            app.BrightnessLabel = uilabel(app.MethodsButtonGroup);
            app.BrightnessLabel.Text = 'Brightness';
            app.HomomorphicButton = uitogglebutton(app.MethodsButtonGroup);
            app.HomomorphicButton.Tag = 'mean';
            app.HomomorphicButton.Text = ' Homomorphic';
            app.GLPFButton = uitogglebutton(app.MethodsButtonGroup);
            app.GLPFButton.Tag = 'negative';
            app.GLPFButton.Text = 'GLPF';
            app.BLPFButton = uitogglebutton(app.MethodsButtonGroup);
            app.BLPFButton.Tag = 'log';
            app.BLPFButton.Text = 'BLPF';
            app.DCButton = uitogglebutton(app.MethodsButtonGroup);
            app.DCButton.Tag = 'gaussian';
            app.DCButton.Text = 'DC';
            app.GHPFButton = uitogglebutton(app.MethodsButtonGroup);
            app.GHPFButton.Tag = 'equalization';
            app.GHPFButton.Text = 'GHPF';
            app.BHPFButton = uitogglebutton(app.MethodsButtonGroup);
            app.BHPFButton.Tag = 'equalization';
            app.BHPFButton.Text = 'BHPF';
            app.PeriodicNoiseRestorationLabel = uilabel(app.MethodsButtonGroup);
            app.PeriodicNoiseRestorationLabel.Text = 'Periodic Noise Restoration';
            app.IdealButton = uitogglebutton(app.MethodsButtonGroup);
            app.IdealButton.Tag = 'mean';
            app.IdealButton.Text = 'Ideal';
            app.Label = uilabel(app.MethodsButtonGroup);
            app.Label.Text = 'Motion Blur Restoration';
            app.PSFButton = uitogglebutton(app.MethodsButtonGroup);
            app.PSFButton.Tag = 'mean';
            app.PSFButton.Text = 'PSF';
            app.ButterworthButton = uitogglebutton(app.MethodsButtonGroup);
            app.ButterworthButton.Tag = 'mean';
            app.ButterworthButton.Text = 'Butterworth';
            app.GaussianButton = uitogglebutton(app.MethodsButtonGroup);
            app.GaussianButton.Tag = 'mean';
            app.GaussianButton.Text = 'Gaussian';
            app.NotchButton = uitogglebutton(app.MethodsButtonGroup);
            app.NotchButton.Tag = 'mean';
            app.NotchButton.Text = 'Notch';
            app.InverseButton = uitogglebutton(app.MethodsButtonGroup);
            app.InverseButton.Tag = 'mean';
            app.InverseButton.Text = 'Inverse';
            app.WienerButton = uitogglebutton(app.MethodsButtonGroup);
            app.WienerButton.Tag = 'mean';
            app.WienerButton.Text = 'Wiener';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Icon = fullfile(pathToMLAPP, 'assets', 'play.png');
            app.ApplyButton.Text = 'Apply';
            app.UndoButton = uibutton(app.UIFigure, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Icon = fullfile(pathToMLAPP, 'assets', 'undo.png');
            app.UndoButton.Text = 'Undo';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Icon = fullfile(pathToMLAPP, 'assets', 'reset.png');
            app.ResetButton.Text = 'Reset';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = fullfile(pathToMLAPP, 'assets', 'save.png');
            app.SaveButton.Text = 'Save';
            app.ReferencePanel = uipanel(app.UIFigure);
            app.ReferencePanel.Title = 'Reference';
            app.ReferenceHistogram = uiaxes(app.ReferencePanel);
            title(app.ReferenceHistogram, 'Title')
            xlabel(app.ReferenceHistogram, 'X')
            ylabel(app.ReferenceHistogram, 'Y')
            zlabel(app.ReferenceHistogram, 'Z')
            app.ReferenceStats = uilistbox(app.ReferencePanel);
            app.ReferenceStats.Items = {'Statistics', 'No statistics yet.'};
            app.ReferenceStats.Value = 'Statistics';
            app.ReferenceImageLabel = uilabel(app.ReferencePanel);
            app.ReferenceImageLabel.Text = 'Reference Image';
            app.ReferenceImage = uiimage(app.ReferencePanel);
            app.HistogramLabel_2 = uilabel(app.ReferencePanel);
            app.HistogramLabel_2.Text = 'Histogram';
            app.ResultPanel = uipanel(app.UIFigure);
            app.ResultPanel.Title = 'Result';
            app.ResultHistogram = uiaxes(app.ResultPanel);
            title(app.ResultHistogram, 'Title')
            xlabel(app.ResultHistogram, 'X')
            ylabel(app.ResultHistogram, 'Y')
            zlabel(app.ResultHistogram, 'Z')
            app.ResultStats = uilistbox(app.ResultPanel);
            app.ResultStats.Items = {'Statistics', 'No statistics yet.'};
            app.ResultStats.Value = 'Statistics';
            app.ResultImageLabel = uilabel(app.ResultPanel);
            app.ResultImageLabel.Text = 'Result Image';
            app.ResultImage = uiimage(app.ResultPanel);
            app.HistogramLabel_3 = uilabel(app.ResultPanel);
            app.HistogramLabel_3.Text = 'Histogram';
            app.ChooseImagePanel = uipanel(app.UIFigure);
            app.ChooseImagePanel.Title = 'Choose Image';
            app.UploadImageButton = uibutton(app.ChooseImagePanel, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Icon = fullfile(pathToMLAPP, 'assets', 'folder.png');
            app.UploadImageButton.Text = 'Upload from Files';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'assets', 'app-logo.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'assets', 'app-copy.png');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'assets', '4-diamonds.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'assets', 'diamond.png');
            app.Image3_2 = uiimage(app.UIFigure);
            app.Image3_2.ImageSource = fullfile(pathToMLAPP, 'assets', 'diamond.png');
            app.Image4_2 = uiimage(app.UIFigure);
            app.Image4_2.ImageSource = fullfile(pathToMLAPP, 'assets', '4-diamonds.png');
            app.Image4_4 = uiimage(app.UIFigure);
            app.Image4_4.ImageSource = fullfile(pathToMLAPP, 'assets', '4-diamonds.png');
            app.Image4_3 = uiimage(app.UIFigure);
            app.Image4_3.ImageSource = fullfile(pathToMLAPP, 'assets', '4-diamonds.png');
            app.GaussianFilterParamsPanel = uipanel(app.UIFigure);
            app.GaussianFilterParamsPanel.Title = 'Gaussian Filter Params';
            app.BlurSpreadEditFieldLabel = uilabel(app.GaussianFilterParamsPanel);
            app.BlurSpreadEditFieldLabel.Text = 'Blur Spread';
            app.SigmaEditField = uieditfield(app.GaussianFilterParamsPanel, 'numeric');
            app.SigmaEditField.Limits = [0.5 5];
            app.SigmaEditField.Value = 1;
            app.BrighteningParamsPanel = uipanel(app.UIFigure);
            app.BrighteningParamsPanel.Title = 'Brightening Params';
            app.OffsetbEditFieldLabel = uilabel(app.BrighteningParamsPanel);
            app.OffsetbEditFieldLabel.Text = 'Offset (b)';
            app.OffsetEditField = uieditfield(app.BrighteningParamsPanel, 'numeric');
            app.OffsetEditField.Limits = [-255 255];
            app.GainaEditFieldLabel = uilabel(app.BrighteningParamsPanel);
            app.GainaEditFieldLabel.Text = 'Gain (a)';
            app.GainEditField = uieditfield(app.BrighteningParamsPanel, 'numeric');
            app.GainEditField.Limits = [0.1 5];
            app.GainEditField.Value = 1;
            app.SpecificationParamsPanel = uipanel(app.UIFigure);
            app.SpecificationParamsPanel.Title = 'Specification Params';
            app.UploadReferenceButton = uibutton(app.SpecificationParamsPanel, 'push');
            app.UploadReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @UploadReferenceButtonPushed, true);
            app.UploadReferenceButton.Icon = fullfile(pathToMLAPP, 'assets', 'folder.png');
            app.UploadReferenceButton.Text = 'Upload from Files';
            app.BeforeInfoLabel_2 = uilabel(app.SpecificationParamsPanel);
            app.BeforeInfoLabel_2.Text = 'Reference Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end